classdef RegressionSolverGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultipleRegressionUIFigure  matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        BrowseButton                matlab.ui.control.Button
        newmat                      matlab.ui.control.Table
        ImportDataFileLabel         matlab.ui.control.Label
        txtbx                       matlab.ui.control.EditField
        SolveButton                 matlab.ui.control.Button
        ChooseOrderLabel            matlab.ui.control.Label
        txtorder                    matlab.ui.control.EditField
        FormulaTextAreaLabel        matlab.ui.control.Label
        txtfrm                      matlab.ui.control.TextArea
        InputMatrixLabel            matlab.ui.control.Label
        GeneratedMatrixLabel        matlab.ui.control.Label
        MultiDimentionalMultiOrderRegressionSolverLabel  matlab.ui.control.Label
        PropertiesLabel             matlab.ui.control.Label
        txtprp                      matlab.ui.control.TextArea
        gbtn                        matlab.ui.control.Button
        ReportNameEditFieldLabel    matlab.ui.control.Label
        txtfile                     matlab.ui.control.EditField
    end


    properties (Access = public)
        A
        gx
        gy
        gn
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.gx=0;
        app.gy=0;
        app.gn=0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.xls'});
%             if isequal(file,0)
%                disp('User selected Cancel');
%             else
%                %disp(['User selected ', fullfile(path,file)]);
%             end
            app.txtbx.Value = fullfile(path,file);
            app.A=xlsread(fullfile(path,file));
            app.UITable.Data = app.A;
            [r,c]=size(app.A);
            
            for i =1:c-1
                colnames(i) = string(strcat('x', num2str(i)));
            end
            colnames(c)='y';
            colnames=colnames';
            app.UITable.ColumnName =colnames;
            
    
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            [r,c]=size(app.A);
            x=app.A(:,1:c-1);
            y=app.A(:,c);
            n = str2num(app.txtorder.Value);
            solution =MainFunction(x,y,n);
            app.newmat.ColumnName =solution.Fstr';
            app.newmat.Data =solution.nx;
            app.txtfrm.Value = strcat('F(x) = ', solution.finalForm);
            app.txtprp.Value = solution.props;
            app.gx=x;
            app.gn=n;
            app.gy=y;
        end

        % Button pushed function: gbtn
        function gbtnButtonPushed(app, event)
            dir=strcat(app.txtfile.Value,"_Report",num2str(randi(1000)));
            fdir=strcat("/",dir);   
            assignin('base','x',app.gx);
            assignin('base','y',app.gy);
            n=app.gn;
            
            props.codeToEvaluate = sprintf('reporter(%s,%s,%f)','x','y',n);
            props.outputDir=fdir;
            props.format='pdf'
            props.showCode = false;
            publish('reporter.m',props)
            
            reporter(app.gx,app.gy,app.gn);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultipleRegressionUIFigure = uifigure;
            app.MultipleRegressionUIFigure.Name = 'Multiple Regression';
            app.UITable = uitable(app.MultipleRegressionUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.BrowseButton = uibutton(app.MultipleRegressionUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.newmat = uitable(app.MultipleRegressionUIFigure);
            app.newmat.ColumnName = '';
            app.newmat.RowName = {};
            app.ImportDataFileLabel = uilabel(app.MultipleRegressionUIFigure);
            app.ImportDataFileLabel.Text = 'Import Data File';
            app.txtbx = uieditfield(app.MultipleRegressionUIFigure, 'text');
            app.SolveButton = uibutton(app.MultipleRegressionUIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ChooseOrderLabel = uilabel(app.MultipleRegressionUIFigure);
            app.ChooseOrderLabel.Text = 'Choose Order';
            app.txtorder = uieditfield(app.MultipleRegressionUIFigure, 'text');
            app.txtorder.Value = '1';
            app.FormulaTextAreaLabel = uilabel(app.MultipleRegressionUIFigure);
            app.FormulaTextAreaLabel.Text = 'Formula';
            app.txtfrm = uitextarea(app.MultipleRegressionUIFigure);
            app.InputMatrixLabel = uilabel(app.MultipleRegressionUIFigure);
            app.InputMatrixLabel.Text = 'Input Matrix';
            app.GeneratedMatrixLabel = uilabel(app.MultipleRegressionUIFigure);
            app.GeneratedMatrixLabel.Text = 'Generated Matrix';
            app.MultiDimentionalMultiOrderRegressionSolverLabel = uilabel(app.MultipleRegressionUIFigure);
            app.MultiDimentionalMultiOrderRegressionSolverLabel.Text = 'Multi Dimentional Multi Order Regression Solver';
            app.PropertiesLabel = uilabel(app.MultipleRegressionUIFigure);
            app.PropertiesLabel.Text = 'Properties';
            app.txtprp = uitextarea(app.MultipleRegressionUIFigure);
            app.gbtn = uibutton(app.MultipleRegressionUIFigure, 'push');
            app.gbtn.ButtonPushedFcn = createCallbackFcn(app, @gbtnButtonPushed, true);
            app.gbtn.Text = 'Generate report';
            app.ReportNameEditFieldLabel = uilabel(app.MultipleRegressionUIFigure);
            app.ReportNameEditFieldLabel.Text = 'Report Name';
            app.txtfile = uieditfield(app.MultipleRegressionUIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = RegressionSolverGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultipleRegressionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultipleRegressionUIFigure)
        end
    end
end